classdef trapds1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        FaseButton_4         matlab.ui.control.Button
        FaseButton_3         matlab.ui.control.Button
        Switch               matlab.ui.control.ToggleSwitch
        TiempoButton_5       matlab.ui.control.Button
        TiempoButton_4       matlab.ui.control.Button
        TiempoButton_3       matlab.ui.control.Button
        UPCUniversidadPeruanadeCienciasAplicadasLabel  matlab.ui.control.Label
        Panel_8              matlab.ui.container.Panel
        ResultadodelAnlisisClnicoIIRLabel  matlab.ui.control.Label
        TextArea_2           matlab.ui.control.TextArea
        GuardarButton_2      matlab.ui.control.Button
        Panel_7              matlab.ui.container.Panel
        ResultadodelAnlisisClnicoFIRLabel  matlab.ui.control.Label
        TextArea             matlab.ui.control.TextArea
        GuardarButton        matlab.ui.control.Button
        MagnitudButton_3     matlab.ui.control.Button
        Panel_6              matlab.ui.container.Panel
        UIAxes_3             matlab.ui.control.UIAxes
        TiempoButton         matlab.ui.control.Button
        MagnitudButton_2     matlab.ui.control.Button
        Panel_5              matlab.ui.container.Panel
        UIAxes_2             matlab.ui.control.UIAxes
        Label                matlab.ui.control.Label
        Panel_4              matlab.ui.container.Panel
        NDropDown            matlab.ui.control.DropDown
        NLabel               matlab.ui.control.Label
        PacienteLabel        matlab.ui.control.Label
        Panel_3              matlab.ui.container.Panel
        UIAxes3              matlab.ui.control.UIAxes
        Panel_2              matlab.ui.container.Panel
        UIAxes2              matlab.ui.control.UIAxes
        Panel                matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
        MagnitudButton       matlab.ui.control.Button
        FaseButton_2         matlab.ui.control.Button
        MagnituddBsButton_2  matlab.ui.control.Button
        MagnitudtpuButton_2  matlab.ui.control.Button
        FaseButton           matlab.ui.control.Button
        MagnituddBsButton    matlab.ui.control.Button
        MagnitudtpuButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        Switch123 % Description
        nfir % Description
        hfir % Description
        Nfir % Description
        Hfir % Description
        FRECfir % Description
        Niir % Description
        Hiir % Description
        FRECiir % Description
        sx % Description
        t % Description
        fs % Description
        num_2 % Description
        dem_2 % Description
        ECG_fir % Description
        Pfir % Description
        Qfir % Description
        Rfir % Description
        Sfir % Description
        ampPfir % Description
        ampQfir % Description
        ampRfir % Description
        ampSfir % Description
        ECG_iir % Description
        Piir % Description
        Qiir % Description
        Riir % Description
        Siir % Description
        ampPiir % Description
        ampQiir % Description
        ampRiir % Description
        ampSiir % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.Switch123 = app.Switch.Value;
            if app.Switch.Value == 0
                cla(app.UIAxes3);
                title(app.UIAxes3,'FIR');
                app.UIAxes3.YLabel.String = 'Y';
                app.UIAxes3.XLabel.String = 'X';
                app.Panel_3.Enable = "off";

                cla(app.UIAxes2);
                title(app.UIAxes2,'IIR');
                app.UIAxes2.YLabel.String = 'Y';
                app.UIAxes2.XLabel.String = 'X';
                app.Panel_2.Enable = "off";
              
                cla(app.UIAxes_3);
                title(app.UIAxes_3,'Señal adquirida');
                app.UIAxes_3.YLabel.String = 'Y';
                app.UIAxes_3.XLabel.String = 'X';
                app.Panel_6.Enable = "off";

                cla(app.UIAxes_2);
                title(app.UIAxes_2,'Señal procesada (FIR)');
                app.UIAxes_2.YLabel.String = 'Y';
                app.UIAxes_2.XLabel.String = 'X';
                app.Panel_5.Enable = "off";
                legend(app.UIAxes_2,'hide')

                cla(app.UIAxes);
                title(app.UIAxes,'Señal procesada (IIR)');
                app.UIAxes.YLabel.String = 'Y';
                app.UIAxes.XLabel.String = 'X';
                app.Panel.Enable = "off";
                legend(app.UIAxes,'hide')

                app.TextArea.Value = "";
                app.Panel_7.Enable = "off";

                app.TextArea_2.Value = "";
                app.Panel_8.Enable = "off";

            else
                app.Panel_2.Enable = "on";
                app.Panel_3.Enable = "on";
                app.Panel_6.Enable = "on";
                app.Panel_5.Enable = "on";
                app.Panel.Enable = "on";
                app.Panel_7.Enable = "on";
                app.Panel_8.Enable = "on";
                
                
                paciente = str2double(app.NDropDown.Value);
                
                switch paciente
                    case 13
                        load("ecg_raw13.mat","x");
                    case 14
                        load("ecg_raw14.mat","x");
                    case 31
                        load("ecg_raw31.mat","x");
                    case 32
                        load("ecg_raw32.mat","x");
                end
                
                app.sx = x;

                app.fs = 500;   %Frecuencia de Muestreo
                Ts = 1/app.fs;  %Periodo de Muestreo
                tc = length(app.sx)*Ts;  %Tiempo de captura total
                app.t = 0:Ts:tc-Ts; %Vector tiempo


                %%Proceso de filtrado
                %Filtro FIR
                app.Nfir = 300;    %Orden                
                app.nfir = 0:app.Nfir;    %Vector de posiciones
                Fc= 20;
                t_c = (Fc/app.fs)*2*pi; %Frecuencia de corte
                app.hfir = sin(t_c*(app.nfir-(app.Nfir/2)))./(pi*(app.nfir-(app.Nfir/2)));

                if(mod(app.Nfir,2)==0)
                    app.hfir(app.Nfir/2+1)=t_c/pi;
                end
                
                w = hamming(app.Nfir+1);
                app.hfir = app.hfir.*w';
                
                num_puntos = 8192;

                [app.Hfir,app.FRECfir] = freqz(app.hfir,1,num_puntos,2*pi);

                %Filtro IIR

                syms z s 
                
                tp = t_c;       %Frecuencia de corte del filtro prototipo pasabajas 
                T = 2;          %ASUMIMOS PERIODO DE MUESTREO T=2
                wc = (2/T)*tan(tp/2);
                
                app.Niir=5;
                beta = pi/app.Niir;
                
                if mod(app.Niir,2)~=0
                    i = -((app.Niir-1)/2):((app.Niir-1)/2);
                    p = wc*exp(1i*(pi + (i*beta)));
                    h_s_num = -prod(p);
                    h_s_dem = prod(s-p);
                else
                    i = -(app.Niir/2):((app.Niir/2)-1);
                    p = wc*exp(1i*(pi + (i*beta) + (beta/2)));
                    h_s_num = prod(p);
                    h_s_dem = prod(s-p);
                end
                
                h_s = h_s_num/h_s_dem;
                
                [num_1,dem_1] = numden(h_s);
                
                num_1 = sym2poly(num_1);
                dem_1 = sym2poly(dem_1);
                
                num_1 = num_1/(dem_1(1));  %dem_2(1) es a0; a0 siempre tiene que ser 1
                dem_1 = dem_1/(dem_1(1));
                
                num_1 = round(num_1,5);
                dem_1 = round(dem_1,5);
                
                H_S = tf(num_1,dem_1);
                
                tb= (2/T)*(1-(z^-1))/(1+(z^-1));
                
                h_z_p = subs(h_s,s,tb);
                
                [app.num_2,app.dem_2] = numden(h_z_p);
                
                app.num_2 = sym2poly(app.num_2);
                app.dem_2 = sym2poly(app.dem_2);
                
                app.num_2 = app.num_2/(app.dem_2(1));  %dem_2(1) es a0; a0 siempre tiene que ser 1
                app.dem_2 = app.dem_2/(app.dem_2(1));
                
                app.num_2 = round(app.num_2,5);
                app.dem_2 = round(app.dem_2,5);
                H_Z = tf(app.num_2,app.dem_2,1,'Variable','z^-1');
                       
                [app.Hiir,app.FRECiir] = freqz(app.num_2,app.dem_2,num_puntos,2*pi);

                plot(app.UIAxes3,app.FRECfir,abs(app.Hfir));
                app.UIAxes3.XGrid="on";
                app.UIAxes3.YGrid="on";
                title(app.UIAxes3,'FIR');
                app.UIAxes3.XLim=[0,app.FRECfir(end)];
                app.UIAxes3.YLim=[0,max(abs(app.Hfir))+0.1];
                app.UIAxes3.YLabel.String = 'Magnitud';
                app.UIAxes3.XLabel.String = 'Frecuencia relativa (θ)';
                
                plot(app.UIAxes2,app.FRECiir,abs(app.Hiir));
                app.UIAxes2.XGrid="on";
                app.UIAxes2.YGrid="on";
                title(app.UIAxes2,'IIR');
                app.UIAxes2.XLim=[0,app.FRECiir(end)];
                app.UIAxes2.YLim=[0,max(abs(app.Hiir))+0.1];
                app.UIAxes2.YLabel.String = 'Magnitud';
                app.UIAxes2.XLabel.String = 'Frecuencia relativa (θ)';

                plot(app.UIAxes_3,app.t,app.sx);
                app.UIAxes_3.XGrid="on";
                app.UIAxes_3.YGrid="on";
                title(app.UIAxes_3,'Señal adquirida');
                app.UIAxes_3.XLim=[0,app.t(end)];
                app.UIAxes_3.YLim=[-1,2];
                app.UIAxes_3.YLabel.String = 'Amplitud (mV)';
                app.UIAxes_3.XLabel.String = 'Tiempo (t)';

                x_fil_fir = filter(app.hfir,1,app.sx);
                app.ECG_fir = normalizacion(app.t,x_fil_fir);
                [app.Pfir,app.Qfir,app.Rfir,app.Sfir,app.ampPfir,app.ampQfir,app.ampRfir,app.ampSfir] = pqrs(app.t,app.ECG_fir,app.fs);

                plot(app.UIAxes_2,app.t,app.ECG_fir);
                app.UIAxes_2.XGrid="on";
                app.UIAxes_2.YGrid="on";
                title(app.UIAxes_2,'Señal procesada (FIR)');
                app.UIAxes_2.XLim=[0,app.t(end)];
                app.UIAxes_2.YLim=[-1.1,1.1];
                app.UIAxes_2.YLabel.String = 'Amplitud (mV)';
                app.UIAxes_2.XLabel.String = 'Tiempo (t)';
                hold(app.UIAxes_2,'on');
                plot(app.UIAxes_2,app.Pfir,app.ampPfir,"o",LineWidth=2);
                plot(app.UIAxes_2,app.Qfir,app.ampQfir,"s",LineWidth=2);
                plot(app.UIAxes_2,app.Rfir,app.ampRfir,"*",LineWidth=2);
                plot(app.UIAxes_2,app.Sfir,app.ampSfir,"v",LineWidth=2);
                hold(app.UIAxes_2,'off');
                legend(app.UIAxes_2,{"ECG","P","Q","R","S"},'Location','south','NumColumns',5)

                x_fil_iir = filter(app.num_2,app.dem_2,app.sx);
                app.ECG_iir = normalizacion(app.t,x_fil_iir);
                [app.Piir,app.Qiir,app.Riir,app.Siir,app.ampPiir,app.ampQiir,app.ampRiir,app.ampSiir] = pqrs(app.t,app.ECG_iir,app.fs);

                plot(app.UIAxes,app.t,app.ECG_iir);
                app.UIAxes.XGrid="on";
                app.UIAxes.YGrid="on";
                title(app.UIAxes,'Señal procesada (IIR)');
                app.UIAxes.XLim=[0,app.t(end)];
                app.UIAxes.YLim=[-1.1,1.1];
                app.UIAxes.YLabel.String = 'Amplitud (mV)';
                app.UIAxes.XLabel.String = 'Tiempo (t)';
                hold(app.UIAxes,'on');
                plot(app.UIAxes,app.Piir,app.ampPiir,"o",LineWidth=2);
                plot(app.UIAxes,app.Qiir,app.ampQiir,"s",LineWidth=2);
                plot(app.UIAxes,app.Riir,app.ampRiir,"*",LineWidth=2);
                plot(app.UIAxes,app.Siir,app.ampSiir,"v",LineWidth=2);
                hold(app.UIAxes,'off');
                legend(app.UIAxes,{"ECG","P","Q","R","S"},'Location','south','NumColumns',5)

                %%Resultados FIR
                app.TextArea.Value = resultados(app.Pfir,app.Qfir,app.Rfir,app.Sfir,app.ampRfir,app.Sfir);

                %%Resultados IIR
                app.TextArea_2.Value = resultados(app.Piir,app.Qiir,app.Riir,app.Siir,app.ampRiir,app.Siir);
                
            end
        end

        % Button pushed function: TiempoButton_4
        function TiempoButton_4Pushed(app, event)
            if (app.Switch123 == 1)
                stem(app.UIAxes3,app.nfir,app.hfir);
                app.UIAxes3.XGrid="on";
                app.UIAxes3.YGrid="on";
                title(app.UIAxes3,'FIR');
                app.UIAxes3.XLim=[0,app.Nfir];
                app.UIAxes3.YLim=[-0.04,0.1];
                app.UIAxes3.YLabel.String = 'Amplitud';
                app.UIAxes3.XLabel.String = 'Tiempo (n)';
            end
        end

        % Button pushed function: MagnitudtpuButton_2
        function MagnitudtpuButton_2Pushed(app, event)
            if (app.Switch123 == 1)    
                plot(app.UIAxes3,app.FRECfir,abs(app.Hfir));
                app.UIAxes3.XGrid="on";
                app.UIAxes3.YGrid="on";
                title(app.UIAxes3,'FIR');
                app.UIAxes3.XLim=[0,app.FRECfir(end)];
                app.UIAxes3.YLim=[0,max(abs(app.Hfir))+0.1];
                app.UIAxes3.YLabel.String = 'Magnitud';
                app.UIAxes3.XLabel.String = 'Frecuencia relativa (θ)';
            end
        end

        % Button pushed function: MagnituddBsButton_2
        function MagnituddBsButton_2Pushed(app, event)
            if (app.Switch123 == 1)    
                plot(app.UIAxes3,app.FRECfir,20*log10(abs(app.Hfir)));
                app.UIAxes3.XGrid="on";
                app.UIAxes3.YGrid="on";
                title(app.UIAxes3,'FIR');
                app.UIAxes3.XLim=[0,app.FRECfir(end)];
                app.UIAxes3.YLim=[min(20*log10(abs(app.Hfir)))-1,0.5];
                app.UIAxes3.YLabel.String = 'Magnitud (dB)';
                app.UIAxes3.XLabel.String = 'Frecuencia relativa (θ)';
            end
        end

        % Button pushed function: FaseButton_2
        function FaseButton_2Pushed(app, event)
            if (app.Switch123 == 1)    
                plot(app.UIAxes3,app.FRECfir,angle(app.Hfir));
                app.UIAxes3.XGrid="on";
                app.UIAxes3.YGrid="on";
                title(app.UIAxes3,'FIR');
                app.UIAxes3.XLim=[0,app.FRECfir(end)];
                app.UIAxes3.YLim=[-pi-0.5,pi+0.5];
                app.UIAxes3.YLabel.String = 'Fase (radianes)';
                app.UIAxes3.XLabel.String = 'Frecuencia relativa (θ)';
            end
        end

        % Button pushed function: MagnitudtpuButton
        function MagnitudtpuButtonPushed(app, event)
            if (app.Switch123 == 1)    
                plot(app.UIAxes2,app.FRECiir,abs(app.Hiir));
                app.UIAxes2.XGrid="on";
                app.UIAxes2.YGrid="on";
                title(app.UIAxes2,'IIR');
                app.UIAxes2.XLim=[0,app.FRECiir(end)];
                app.UIAxes2.YLim=[0,max(abs(app.Hiir))+0.1];
                app.UIAxes2.YLabel.String = 'Magnitud';
                app.UIAxes2.XLabel.String = 'Frecuencia relativa (θ)';
            end
        end

        % Button pushed function: MagnituddBsButton
        function MagnituddBsButtonPushed(app, event)
            if (app.Switch123 == 1)    
                plot(app.UIAxes2,app.FRECiir,20*log10(abs(app.Hiir)));
                app.UIAxes2.XGrid="on";
                app.UIAxes2.YGrid="on";
                title(app.UIAxes2,'IIR');
                app.UIAxes2.XLim=[0,app.FRECiir(end)];
                app.UIAxes2.YLim=[min(20*log10(abs(app.Hiir)))-1,0.5];
                app.UIAxes2.YLabel.String = 'Magnitud (dB)';
                app.UIAxes2.XLabel.String = 'Frecuencia relativa (θ)';
            end
        end

        % Button pushed function: FaseButton
        function FaseButtonPushed(app, event)
            if (app.Switch123 == 1)    
                plot(app.UIAxes2,app.FRECiir,angle(app.Hiir));
                app.UIAxes2.XGrid="on";
                app.UIAxes2.YGrid="on";
                title(app.UIAxes2,'IIR');
                app.UIAxes2.XLim=[0,app.FRECiir(end)];
                app.UIAxes2.YLim=[-pi-0.5,pi+0.5];
                app.UIAxes2.YLabel.String = 'Fase (radianes)';
                app.UIAxes2.XLabel.String = 'Frecuencia relativa (θ)';
            end
        end

        % Button pushed function: MagnitudButton_3
        function MagnitudButton_3Pushed(app, event)
            if (app.Switch123 == 1)
                [X,FREC] = fourier(app.sx,app.fs);
                plot(app.UIAxes_3,FREC,abs(X));
                app.UIAxes_3.XGrid="on";
                app.UIAxes_3.YGrid="on";
                title(app.UIAxes_3,'Señal adquirida');
                app.UIAxes_3.XLim=[0,FREC(end)];
                app.UIAxes_3.YLim=[0,max(abs(X))+10];
                app.UIAxes_3.YLabel.String = 'Magnitud';
                app.UIAxes_3.XLabel.String = 'Frecuencia (Hz)';
            end
        end

        % Button pushed function: TiempoButton
        function TiempoButtonPushed(app, event)
            if (app.Switch123 == 1)
                plot(app.UIAxes_3,app.t,app.sx);
                app.UIAxes_3.XGrid="on";
                app.UIAxes_3.YGrid="on";
                title(app.UIAxes_3,'Señal adquirida');
                app.UIAxes_3.XLim=[0,app.t(end)];
                app.UIAxes_3.YLim=[-1,2];
                app.UIAxes_3.YLabel.String = 'Amplitud (mV)';
                app.UIAxes_3.XLabel.String = 'Tiempo (t)';
            end
        end

        % Value changed function: NDropDown
        function NDropDownValueChanged(app, event)
        if (app.Switch123==1)
            paciente = str2double(app.NDropDown.Value);
            
            switch paciente
                case 13
                    load("ecg_raw13.mat","x");
                case 14
                    load("ecg_raw14.mat","x");
                case 31
                    load("ecg_raw31.mat","x");
                case 32
                    load("ecg_raw32.mat","x");
            end
                
            app.sx = x;
            
            plot(app.UIAxes_3,app.t,app.sx);
            app.UIAxes_3.XGrid="on";
            app.UIAxes_3.YGrid="on";
            title(app.UIAxes_3,'Señal adquirida');
            app.UIAxes_3.XLim=[0,app.t(end)];
            app.UIAxes_3.YLim=[-1,2];
            app.UIAxes_3.YLabel.String = 'Amplitud (mV)';
            app.UIAxes_3.XLabel.String = 'Tiempo (t)';

            x_fil_fir = filter(app.hfir,1,app.sx);
            app.ECG_fir = normalizacion(app.t,x_fil_fir);
            [app.Pfir,app.Qfir,app.Rfir,app.Sfir,app.ampPfir,app.ampQfir,app.ampRfir,app.ampSfir] = pqrs(app.t,app.ECG_fir,app.fs);

            plot(app.UIAxes_2,app.t,app.ECG_fir);
            app.UIAxes_2.XGrid="on";
            app.UIAxes_2.YGrid="on";
            title(app.UIAxes_2,'Señal procesada (FIR)');
            app.UIAxes_2.XLim=[0,app.t(end)];
            app.UIAxes_2.YLim=[-1.1,1.1];
            app.UIAxes_2.YLabel.String = 'Amplitud (mV)';
            app.UIAxes_2.XLabel.String = 'Tiempo (t)';
            hold(app.UIAxes_2,'on');
            plot(app.UIAxes_2,app.Pfir,app.ampPfir,"o",LineWidth=2);
            plot(app.UIAxes_2,app.Qfir,app.ampQfir,"s",LineWidth=2);
            plot(app.UIAxes_2,app.Rfir,app.ampRfir,"*",LineWidth=2);
            plot(app.UIAxes_2,app.Sfir,app.ampSfir,"v",LineWidth=2);
            hold(app.UIAxes_2,'off');
            legend(app.UIAxes_2,{"ECG","P","Q","R","S"},'Location','south','NumColumns',5)

            x_fil_iir = filter(app.num_2,app.dem_2,app.sx);
            app.ECG_iir = normalizacion(app.t,x_fil_iir);
            [app.Piir,app.Qiir,app.Riir,app.Siir,app.ampPiir,app.ampQiir,app.ampRiir,app.ampSiir] = pqrs(app.t,app.ECG_iir,app.fs);

            plot(app.UIAxes,app.t,app.ECG_iir);
            app.UIAxes.XGrid="on";
            app.UIAxes.YGrid="on";
            title(app.UIAxes,'Señal procesada (IIR)');
            app.UIAxes.XLim=[0,app.t(end)];
            app.UIAxes.YLim=[-1.1,1.1];
            app.UIAxes.YLabel.String = 'Amplitud (mV)';
            app.UIAxes.XLabel.String = 'Tiempo (t)';
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.Piir,app.ampPiir,"o",LineWidth=2);
            plot(app.UIAxes,app.Qiir,app.ampQiir,"s",LineWidth=2);
            plot(app.UIAxes,app.Riir,app.ampRiir,"*",LineWidth=2);
            plot(app.UIAxes,app.Siir,app.ampSiir,"v",LineWidth=2);
            hold(app.UIAxes,'off');
            legend(app.UIAxes,{"ECG","P","Q","R","S"},'Location','south','NumColumns',5)

            %%Resultados FIR
            app.TextArea.Value = resultados(app.Pfir,app.Qfir,app.Rfir,app.Sfir,app.ampRfir,app.Sfir);

            %%Resultados IIR
            app.TextArea_2.Value = resultados(app.Piir,app.Qiir,app.Riir,app.Siir,app.ampRiir,app.Siir);

        end 
        end

        % Button pushed function: MagnitudButton_2
        function MagnitudButton_2Pushed(app, event)
            if (app.Switch123==1)
                [X,FREC] = fourier(app.ECG_fir,app.fs);
                legend(app.UIAxes_2,'hide')
                plot(app.UIAxes_2,FREC,abs(X));
                app.UIAxes_2.XGrid="on";
                app.UIAxes_2.YGrid="on";
                title(app.UIAxes_2,'Señal procesada (FIR)');
                app.UIAxes_2.XLim=[0,FREC(end)];
                app.UIAxes_2.YLim=[0,630];
                app.UIAxes_2.YLabel.String = 'Magnitud';
                app.UIAxes_2.XLabel.String = 'Frecuencia (Hz)';
            end
        end

        % Button pushed function: TiempoButton_5
        function TiempoButton_5Pushed(app, event)
            if (app.Switch123==1)
                plot(app.UIAxes_2,app.t,app.ECG_fir);
                app.UIAxes_2.XGrid="on";
                app.UIAxes_2.YGrid="on";
                title(app.UIAxes_2,'Señal procesada (FIR)');
                app.UIAxes_2.XLim=[0,app.t(end)];
                app.UIAxes_2.YLim=[-1.1,1.1];
                app.UIAxes_2.YLabel.String = 'Amplitud (mV)';
                app.UIAxes_2.XLabel.String = 'Tiempo (t)';
                hold(app.UIAxes_2,'on');
                plot(app.UIAxes_2,app.Pfir,app.ampPfir,"o",LineWidth=2);
                plot(app.UIAxes_2,app.Qfir,app.ampQfir,"s",LineWidth=2);
                plot(app.UIAxes_2,app.Rfir,app.ampRfir,"*",LineWidth=2);
                plot(app.UIAxes_2,app.Sfir,app.ampSfir,"v",LineWidth=2);
                hold(app.UIAxes_2,'off');
                legend(app.UIAxes_2,{"ECG","P","Q","R","S"},'Location','south','NumColumns',5) 
            end
        end

        % Button pushed function: MagnitudButton
        function MagnitudButtonPushed(app, event)
            if (app.Switch123==1)
                [X,FREC] = fourier(app.ECG_iir,app.fs);
                legend(app.UIAxes,'hide')
                plot(app.UIAxes,FREC,abs(X));
                app.UIAxes.XGrid="on";
                app.UIAxes.YGrid="on";
                title(app.UIAxes,'Señal procesada (IIR)');
                app.UIAxes.XLim=[0,FREC(end)];
                app.UIAxes.YLim=[0,630];
                app.UIAxes.YLabel.String = 'Magnitud';
                app.UIAxes.XLabel.String = 'Frecuencia (Hz)';
            end
        end

        % Button pushed function: TiempoButton_3
        function TiempoButton_3Pushed(app, event)
            if (app.Switch123==1)
                plot(app.UIAxes,app.t,app.ECG_iir);
                app.UIAxes.XGrid="on";
                app.UIAxes.YGrid="on";
                title(app.UIAxes,'Señal procesada (IIR)');
                app.UIAxes.XLim=[0,app.t(end)];
                app.UIAxes.YLim=[-1.1,1.1];
                app.UIAxes.YLabel.String = 'Amplitud (mV)';
                app.UIAxes.XLabel.String = 'Tiempo (t)';
                hold(app.UIAxes,'on');
                plot(app.UIAxes,app.Piir,app.ampPiir,"o",LineWidth=2);
                plot(app.UIAxes,app.Qiir,app.ampQiir,"s",LineWidth=2);
                plot(app.UIAxes,app.Riir,app.ampRiir,"*",LineWidth=2);
                plot(app.UIAxes,app.Siir,app.ampSiir,"v",LineWidth=2);
                hold(app.UIAxes,'off');
                legend(app.UIAxes,{"ECG","P","Q","R","S"},'Location','south','NumColumns',5)
            end
        end

        % Button pushed function: FaseButton_3
        function FaseButton_3Pushed(app, event)
            if (app.Switch123==1)
                [X,FREC] = fourier(app.ECG_fir,app.fs);
                legend(app.UIAxes_2,'hide')
                plot(app.UIAxes_2,FREC,angle(X));
                app.UIAxes_2.XGrid="on";
                app.UIAxes_2.YGrid="on";
                title(app.UIAxes_2,'Señal procesada (FIR)');
                app.UIAxes_2.XLim=[0,FREC(end)];
                app.UIAxes_2.YLim=[-pi-0.5,pi+0.5];
                app.UIAxes_2.YLabel.String = 'Magnitud';
                app.UIAxes_2.XLabel.String = 'Frecuencia (Hz)';
            end
        end

        % Button pushed function: FaseButton_4
        function FaseButton_4Pushed(app, event)
            if (app.Switch123==1)
                [X,FREC] = fourier(app.ECG_iir,app.fs);
                legend(app.UIAxes,'hide')
                plot(app.UIAxes,FREC,angle(X));
                app.UIAxes.XGrid="on";
                app.UIAxes.YGrid="on";
                title(app.UIAxes,'Señal procesada (FIR)');
                app.UIAxes.XLim=[0,FREC(end)];
                app.UIAxes.YLim=[-pi-0.5,pi+0.5];
                app.UIAxes.YLabel.String = 'Magnitud';
                app.UIAxes.XLabel.String = 'Frecuencia (Hz)';
            end
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            if (app.Switch123==1)
                datafir = app.TextArea.Value;
                namefir = "resultados_paciente_" + string(app.NDropDown.Value) + "_fir.xlsx";
                writecell(datafir, namefir);
            end
        end

        % Button pushed function: GuardarButton_2
        function GuardarButton_2Pushed(app, event)
            if (app.Switch123==1)
                dataiir = app.TextArea_2.Value;
                nameiir = "resultados_paciente_" + string(app.NDropDown.Value) + "_iir.xlsx";
                writecell(dataiir, nameiir);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MagnitudtpuButton = uibutton(app.UIFigure, 'push');
            app.MagnitudtpuButton.ButtonPushedFcn = createCallbackFcn(app, @MagnitudtpuButtonPushed, true);
            app.MagnitudtpuButton.Text = 'Magnitud (tpu)';
            app.MagnituddBsButton = uibutton(app.UIFigure, 'push');
            app.MagnituddBsButton.ButtonPushedFcn = createCallbackFcn(app, @MagnituddBsButtonPushed, true);
            app.MagnituddBsButton.Text = 'Magnitud (dBs)';
            app.FaseButton = uibutton(app.UIFigure, 'push');
            app.FaseButton.ButtonPushedFcn = createCallbackFcn(app, @FaseButtonPushed, true);
            app.FaseButton.Text = 'Fase';
            app.MagnitudtpuButton_2 = uibutton(app.UIFigure, 'push');
            app.MagnitudtpuButton_2.ButtonPushedFcn = createCallbackFcn(app, @MagnitudtpuButton_2Pushed, true);
            app.MagnitudtpuButton_2.Text = 'Magnitud (tpu)';
            app.MagnituddBsButton_2 = uibutton(app.UIFigure, 'push');
            app.MagnituddBsButton_2.ButtonPushedFcn = createCallbackFcn(app, @MagnituddBsButton_2Pushed, true);
            app.MagnituddBsButton_2.Text = 'Magnitud (dBs)';
            app.FaseButton_2 = uibutton(app.UIFigure, 'push');
            app.FaseButton_2.ButtonPushedFcn = createCallbackFcn(app, @FaseButton_2Pushed, true);
            app.FaseButton_2.Text = 'Fase';
            app.MagnitudButton = uibutton(app.UIFigure, 'push');
            app.MagnitudButton.ButtonPushedFcn = createCallbackFcn(app, @MagnitudButtonPushed, true);
            app.MagnitudButton.Text = 'Magnitud';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Enable = 'off';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Señal procesada (IIR)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Enable = 'off';
            app.UIAxes2 = uiaxes(app.Panel_2);
            title(app.UIAxes2, 'IIR')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Enable = 'off';
            app.UIAxes3 = uiaxes(app.Panel_3);
            title(app.UIAxes3, 'FIR')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Panel_4 = uipanel(app.UIFigure);
            app.PacienteLabel = uilabel(app.Panel_4);
            app.PacienteLabel.Text = 'Paciente';
            app.NLabel = uilabel(app.Panel_4);
            app.NLabel.Text = 'N°';
            app.NDropDown = uidropdown(app.Panel_4);
            app.NDropDown.Items = {'13', '14', '31', '32'};
            app.NDropDown.ValueChangedFcn = createCallbackFcn(app, @NDropDownValueChanged, true);
            app.NDropDown.Value = '13';
            app.Label = uilabel(app.UIFigure);
            app.Panel_5 = uipanel(app.UIFigure);
            app.Panel_5.Enable = 'off';
            app.UIAxes_2 = uiaxes(app.Panel_5);
            title(app.UIAxes_2, 'Señal procesada (FIR) ')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.MagnitudButton_2 = uibutton(app.UIFigure, 'push');
            app.MagnitudButton_2.ButtonPushedFcn = createCallbackFcn(app, @MagnitudButton_2Pushed, true);
            app.MagnitudButton_2.Text = 'Magnitud';
            app.TiempoButton = uibutton(app.UIFigure, 'push');
            app.TiempoButton.ButtonPushedFcn = createCallbackFcn(app, @TiempoButtonPushed, true);
            app.TiempoButton.Text = 'Tiempo';
            app.Panel_6 = uipanel(app.UIFigure);
            app.Panel_6.Enable = 'off';
            app.UIAxes_3 = uiaxes(app.Panel_6);
            title(app.UIAxes_3, 'Señal adquirida')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.MagnitudButton_3 = uibutton(app.UIFigure, 'push');
            app.MagnitudButton_3.ButtonPushedFcn = createCallbackFcn(app, @MagnitudButton_3Pushed, true);
            app.MagnitudButton_3.Text = 'Magnitud';
            app.Panel_7 = uipanel(app.UIFigure);
            app.Panel_7.Enable = 'off';
            app.GuardarButton = uibutton(app.Panel_7, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.TextArea = uitextarea(app.Panel_7);
            app.TextArea.Editable = 'off';
            app.ResultadodelAnlisisClnicoFIRLabel = uilabel(app.Panel_7);
            app.ResultadodelAnlisisClnicoFIRLabel.Text = 'Resultado del Análisis Clínico (FIR)';
            app.Panel_8 = uipanel(app.UIFigure);
            app.Panel_8.Enable = 'off';
            app.GuardarButton_2 = uibutton(app.Panel_8, 'push');
            app.GuardarButton_2.ButtonPushedFcn = createCallbackFcn(app, @GuardarButton_2Pushed, true);
            app.GuardarButton_2.Text = 'Guardar';
            app.TextArea_2 = uitextarea(app.Panel_8);
            app.ResultadodelAnlisisClnicoIIRLabel = uilabel(app.Panel_8);
            app.ResultadodelAnlisisClnicoIIRLabel.Text = 'Resultado del Análisis Clínico (IIR)';
            app.UPCUniversidadPeruanadeCienciasAplicadasLabel = uilabel(app.UIFigure);
            app.UPCUniversidadPeruanadeCienciasAplicadasLabel.Text = 'UPC - Universidad Peruana de Ciencias Aplicadas';
            app.TiempoButton_3 = uibutton(app.UIFigure, 'push');
            app.TiempoButton_3.ButtonPushedFcn = createCallbackFcn(app, @TiempoButton_3Pushed, true);
            app.TiempoButton_3.Text = 'Tiempo';
            app.TiempoButton_4 = uibutton(app.UIFigure, 'push');
            app.TiempoButton_4.ButtonPushedFcn = createCallbackFcn(app, @TiempoButton_4Pushed, true);
            app.TiempoButton_4.Text = 'Tiempo';
            app.TiempoButton_5 = uibutton(app.UIFigure, 'push');
            app.TiempoButton_5.ButtonPushedFcn = createCallbackFcn(app, @TiempoButton_5Pushed, true);
            app.TiempoButton_5.Text = 'Tiempo';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'◯', '|'};
            app.Switch.ItemsData = [0 1];
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 0;
            app.FaseButton_3 = uibutton(app.UIFigure, 'push');
            app.FaseButton_3.ButtonPushedFcn = createCallbackFcn(app, @FaseButton_3Pushed, true);
            app.FaseButton_3.Text = 'Fase';
            app.FaseButton_4 = uibutton(app.UIFigure, 'push');
            app.FaseButton_4.ButtonPushedFcn = createCallbackFcn(app, @FaseButton_4Pushed, true);
            app.FaseButton_4.Text = 'Fase';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trapds1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end